classdef app_BlackScholes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BlackScholesOptionPricingModulePanel  matlab.ui.container.Panel
        ImpliedVolatility          matlab.ui.control.NumericEditField
        ImpliedVolatilityLabel     matlab.ui.control.Label
        CALLDelta                  matlab.ui.control.NumericEditField
        CALLDeltaEditField_2Label  matlab.ui.control.Label
        PUTPrice                   matlab.ui.control.NumericEditField
        PUTPriceEditFieldLabel     matlab.ui.control.Label
        PUTDelta                   matlab.ui.control.NumericEditField
        PUTDeltaEditFieldLabel     matlab.ui.control.Label
        PRICE                      matlab.ui.control.Button
        CALLPrice                  matlab.ui.control.NumericEditField
        CALLPriceLabel             matlab.ui.control.Label
        MarketPrice                matlab.ui.control.NumericEditField
        MarketPriceLabel           matlab.ui.control.Label
        sigmaVal                   matlab.ui.control.NumericEditField
        sigmaEditFieldLabel        matlab.ui.control.Label
        Interest                   matlab.ui.control.NumericEditField
        rEditFieldLabel            matlab.ui.control.Label
        Time                       matlab.ui.control.NumericEditField
        TEditFieldLabel            matlab.ui.control.Label
        Strike                     matlab.ui.control.NumericEditField
        StrikeEditFieldLabel       matlab.ui.control.Label
        StockPrice                 matlab.ui.control.NumericEditField
        StockPriceEditFieldLabel   matlab.ui.control.Label
    end

    methods (Access = private)
        function price_option(app)

            %%% User-defined parameters:
            global r sigma T stock_price X market_price

            %%% Output
            global call put call_Delta put_Delta impv

            r = app.Interest.Value;
            sigma = app.sigmaVal.Value;
            T = app.Time.Value/365;
            X = app.Strike.Value;
            stock_price = app.StockPrice.Value;
            market_price = app.MarketPrice.Value;

            app_BlackScholes.get_price;
        end
    end

    methods(Static)

        function get_price()
            global sigma stock_price X r T market_price
            global call put call_Delta put_Delta impv
            [call, put] = blsprice(stock_price,X,r,T,sigma);
            [call_Delta, put_Delta] = blsdelta(stock_price,X,r,T,sigma);
            impv = blsimpv(stock_price,X,r,T,market_price);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PRICE
        function PRICEPushed(app, event)
            global call put call_Delta put_Delta impv
            price_option(app);
            app.CALLPrice.Value = call;
            app.CALLDelta.Value = call_Delta;
            app.PUTPrice.Value = put;
            app.PUTDelta.Value = put_Delta;
            app.ImpliedVolatility.Value = impv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlackScholesOptionPricingModulePanel = uipanel(app.UIFigure);
            app.BlackScholesOptionPricingModulePanel.Title = 'Black-Scholes Option Pricing Module';
            app.StockPriceEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.StockPriceEditFieldLabel.Text = 'Stock Price';
            app.StockPrice = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.StockPrice.ValueDisplayFormat = '%.2f';
            app.StockPrice.Value = 477.5;
            app.StrikeEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.StrikeEditFieldLabel.Text = 'Strike';
            app.Strike = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.Strike.ValueDisplayFormat = '%.2f';
            app.Strike.Value = 475;
            app.TEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.TEditFieldLabel.Text = 'T';
            app.Time = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.Time.Value = 50;
            app.rEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.rEditFieldLabel.Text = 'r';
            app.Interest = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.Interest.Value = 0.07;
            app.sigmaEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaVal = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.sigmaVal.Value = 0.3;
            app.MarketPriceLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.MarketPriceLabel.Text = 'Market Price';
            app.MarketPrice = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.MarketPrice.ValueDisplayFormat = '%.2f';
            app.MarketPrice.Value = 11.83;
            app.CALLPriceLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.CALLPriceLabel.Text = 'CALL Price';
            app.CALLPrice = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.CALLPrice.ValueDisplayFormat = '%.2f';
            app.PRICE = uibutton(app.BlackScholesOptionPricingModulePanel, 'push');
            app.PRICE.ButtonPushedFcn = createCallbackFcn(app, @PRICEPushed, true);
            app.PRICE.Text = 'PRICE';
            app.PUTDeltaEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.PUTDeltaEditFieldLabel.Text = 'PUT Delta';
            app.PUTDelta = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.PUTDelta.ValueDisplayFormat = '%.2f';
            app.PUTPriceEditFieldLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.PUTPriceEditFieldLabel.Text = 'PUT Price';
            app.PUTPrice = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.PUTPrice.ValueDisplayFormat = '%.2f';
            app.CALLDeltaEditField_2Label = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.CALLDeltaEditField_2Label.Text = 'CALL Delta';
            app.CALLDelta = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.CALLDelta.ValueDisplayFormat = '%.2f';
            app.ImpliedVolatilityLabel = uilabel(app.BlackScholesOptionPricingModulePanel);
            app.ImpliedVolatilityLabel.Text = 'Implied Volatility';
            app.ImpliedVolatility = uieditfield(app.BlackScholesOptionPricingModulePanel, 'numeric');
            app.ImpliedVolatility.ValueDisplayFormat = '%.2f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_BlackScholes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end