classdef app_profile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TradeTypeListBox          matlab.ui.control.ListBox
        nCallSlider               matlab.ui.control.Slider
        nPutSlider                matlab.ui.control.Slider
        Delta_CallSlider          matlab.ui.control.Slider
        Delta_PutSlider           matlab.ui.control.Slider
        StockPrice                matlab.ui.control.NumericEditField
        XVal                      matlab.ui.control.NumericEditField
        CallPremium               matlab.ui.control.NumericEditField
        PutPremium                matlab.ui.control.NumericEditField
        StockRange                matlab.ui.control.NumericEditField
        Y_min                     matlab.ui.control.NumericEditField
        Y_max                     matlab.ui.control.NumericEditField
        Y_maxEditFieldLabel       matlab.ui.control.Label
        Y_minEditFieldLabel       matlab.ui.control.Label
        StockRangeEditFieldLabel  matlab.ui.control.Label
        PLabel                    matlab.ui.control.Label
        CLabel                    matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        StockPriceEditFieldLabel  matlab.ui.control.Label
        TradeTypeListBoxLabel     matlab.ui.control.Label
        Delta_PutSliderLabel      matlab.ui.control.Label
        Delta_CallSliderLabel     matlab.ui.control.Label
        CallsLabel                matlab.ui.control.Label
        PutsLabel                 matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function update_values(app)
            global stock_price X call_premium put_premium stock_range
            global S_min S_max Y_min Y_max S_grid
            global call_at_maturity put_at_maturity
          
            stock_price = app.StockPrice.Value;
            X = app.XVal.Value;
            call_premium = app.CallPremium.Value;
            put_premium = app.PutPremium.Value;

            stock_range = app.StockRange.Value;

            S_min = floor(stock_price*(1-stock_range));
            S_max = ceil(stock_price*(1+stock_range));
            S_grid = (S_min:1:S_max);
            Y_min = app.Y_min.Value; 
            Y_max = app.Y_max.Value; 

            call_at_maturity = max(S_grid-X,0);
            put_at_maturity = max(X-S_grid,0);
        end
        
        function plot_profile(app)
            global n_call n_put Delta_call Delta_put
            global stock_price call_premium put_premium
            global S_min S_max Y_min Y_max S_grid
            global call_at_maturity put_at_maturity
          
            switch app.TradeTypeListBox.Value
                case 'Buy Call-Buy Put'
                    y_option = n_call*(call_at_maturity - call_premium) + n_put*(put_at_maturity - put_premium);
                    y_stock  = n_call*Delta_call*(stock_price - S_grid) + n_put*Delta_put*(stock_price - S_grid);
                    Effective_Delta = -n_call*Delta_call - n_put*Delta_put; 
                case 'Buy Call-Sell Put'
                    y_option = n_call*(call_at_maturity - call_premium) + n_put*(put_premium - put_at_maturity);
                    y_stock  = n_call*Delta_call*(stock_price - S_grid) + n_put*Delta_put*(S_grid - stock_price);
                    Effective_Delta = -n_call*Delta_call + n_put*Delta_put; 
                case 'Sell Call-Buy Put'
                    y_option = n_call*(call_premium - call_at_maturity) + n_put*(put_at_maturity - put_premium);
                    y_stock  = n_call*Delta_call*(S_grid - stock_price) + n_put*Delta_put*(stock_price - S_grid);
                    Effective_Delta = n_call*Delta_call - n_put*Delta_put; 
                case 'Sell Call-Sell Put'
                    y_option = n_call*(call_premium - call_at_maturity) + n_put*(put_premium - put_at_maturity);
                    y_stock  = n_call*Delta_call*(S_grid - stock_price) + n_put*Delta_put*(S_grid - stock_price);
                    Effective_Delta = n_call*Delta_call + n_put*Delta_put; 
            end  
            y = 100*(y_option + y_stock);
            yp = subplus(y); y_neg = find(yp==0); y_pos = find(yp > 0);
            if ~isempty(y_neg) && ~isempty(y_pos)
                if S_grid(y_neg(1)) > S_min && S_grid(y_neg(end)) < S_max
                    pos_str = ['(',num2str(0),', ',num2str(S_grid(y_neg(1)-1)),') and (',num2str(S_grid(y_neg(end)+1)),', \infty)'];
                else
                    pos_range = S_grid(y_pos);
                    if y_pos(1) == 1
                        pos_str = ['(',num2str(0),',',num2str(pos_range(end)),')'];
                    else
                        pos_str = ['(',num2str(pos_range(1)), ', ',num2str(pos_range(end)),')'];
                    end
                end
            elseif isempty(y_neg)
                pos_str = '(0,\infty)';
            else
                pos_str = '\infty';
            end

            title_str = {['100\Delta_{effective} = ',num2str(round(100*Effective_Delta)),...
                '. Make money if S \in ',pos_str]...
                ['n_{call} = ',num2str(n_call),', n_{put} = ',num2str(n_put)...
                 ', \Delta_{call} = ',num2str(Delta_call,'%.2f'),', \Delta_{put} = ',num2str(Delta_put,'%.2f')]};

            plot(app.UIAxes,S_grid,y,'LineWidth',2);
            z = line(app.UIAxes,S_grid,0*S_grid,'LineWidth',2); z.Color = 'k';
            app.UIAxes.YLim = [Y_min,Y_max];
            app.UIAxes.XLim = [S_min,S_max];
            title(app.UIAxes, title_str,'FontSize',24);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global n_call n_put
            global Delta_call Delta_put
            n_call = app.nCallSlider.Value;
            n_put = app.nPutSlider.Value;
            Delta_call = app.Delta_CallSlider.Value;
            Delta_put = app.Delta_PutSlider.Value;
            update_values(app); plot_profile(app);
        end

        % Value changed function: nCallSlider
        function nCallSliderValueChanged(app, event)
            global n_call
            n_call = round(event.Value);
            app.nCallSlider.Value = n_call;
            update_values(app); plot_profile(app);
        end

        % Value changed function: nPutSlider
        function nPutSliderValueChanged(app, event)
            global n_put
            n_put = round(event.Value);
            app.nPutSlider.Value = n_put;
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta_CallSlider
        function Delta_CallSliderValueChanging(app, event)
            global Delta_call
            Delta_call = event.Value;
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta_PutSlider
        function Delta_PutSliderValueChanging(app, event)
            global Delta_put
            Delta_put = event.Value; 
            update_values(app); plot_profile(app);
        end

        % Clicked callback: TradeTypeListBox
        function TradeTypeListBoxClicked(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: StockPrice
        function StockPriceValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Y_max
        function Y_maxValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Y_min
        function Y_minValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: PutPremium
        function PutPremiumValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: CallPremium
        function CallPremiumValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: XVal
        function XValValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: StockRange
        function StockRangeValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1758, 1758};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {555, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PutsLabel = uilabel(app.LeftPanel);
            app.PutsLabel.Text = 'nPut';
            app.CallsLabel = uilabel(app.LeftPanel);
            app.CallsLabel.Text = 'nCall';
            app.Delta_CallSliderLabel = uilabel(app.LeftPanel);
            app.Delta_CallSliderLabel.Text = 'Delta_Call';
            app.Delta_PutSliderLabel = uilabel(app.LeftPanel);
            app.Delta_PutSliderLabel.Text = 'Delta_Put';
            app.TradeTypeListBoxLabel = uilabel(app.LeftPanel);
            app.TradeTypeListBoxLabel.Text = 'Trade Type';
            app.StockPriceEditFieldLabel = uilabel(app.LeftPanel);
            app.StockPriceEditFieldLabel.Text = 'Stock Price';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.CLabel = uilabel(app.LeftPanel);
            app.CLabel.Text = 'C';
            app.PLabel = uilabel(app.LeftPanel);
            app.PLabel.Text = 'P';
            app.StockRangeEditFieldLabel = uilabel(app.LeftPanel);
            app.StockRangeEditFieldLabel.Text = 'Stock Range';
            app.Y_minEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_minEditFieldLabel.Text = 'Y_min';
            app.Y_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_maxEditFieldLabel.Text = 'Y_max';
            app.Y_max = uieditfield(app.LeftPanel, 'numeric');
            app.Y_max.ValueChangedFcn = createCallbackFcn(app, @Y_maxValueChanged, true);
            app.Y_max.Value = 500;
            app.Y_min = uieditfield(app.LeftPanel, 'numeric');
            app.Y_min.ValueChangedFcn = createCallbackFcn(app, @Y_minValueChanged, true);
            app.Y_min.Value = -500;
            app.StockRange = uieditfield(app.LeftPanel, 'numeric');
            app.StockRange.ValueChangedFcn = createCallbackFcn(app, @StockRangeValueChanged, true);
            app.StockRange.Value = 0.05;
            app.PutPremium = uieditfield(app.LeftPanel, 'numeric');
            app.PutPremium.ValueChangedFcn = createCallbackFcn(app, @PutPremiumValueChanged, true);
            app.PutPremium.Value = 14.5;
            app.CallPremium = uieditfield(app.LeftPanel, 'numeric');
            app.CallPremium.ValueChangedFcn = createCallbackFcn(app, @CallPremiumValueChanged, true);
            app.CallPremium.Value = 9.8;
            app.XVal = uieditfield(app.LeftPanel, 'numeric');
            app.XVal.ValueChangedFcn = createCallbackFcn(app, @XValValueChanged, true);
            app.XVal.Value = 150;
            app.StockPrice = uieditfield(app.LeftPanel, 'numeric');
            app.StockPrice.ValueChangedFcn = createCallbackFcn(app, @StockPriceValueChanged, true);
            app.StockPrice.Value = 149;
            app.Delta_PutSlider = uislider(app.LeftPanel);
            app.Delta_PutSlider.Limits = [-1 0];
            app.Delta_PutSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0];
            app.Delta_PutSlider.ValueChangingFcn = createCallbackFcn(app, @Delta_PutSliderValueChanging, true);
            app.Delta_PutSlider.Value = -0.48;
            app.Delta_CallSlider = uislider(app.LeftPanel);
            app.Delta_CallSlider.Limits = [0 1];
            app.Delta_CallSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Delta_CallSlider.ValueChangingFcn = createCallbackFcn(app, @Delta_CallSliderValueChanging, true);
            app.Delta_CallSlider.Value = 0.57;
            app.nPutSlider = uislider(app.LeftPanel);
            app.nPutSlider.Limits = [0 5];
            app.nPutSlider.MajorTicks = [0 1 2 3 4 5];
            app.nPutSlider.ValueChangedFcn = createCallbackFcn(app, @nPutSliderValueChanged, true);
            app.nPutSlider.MinorTicks = [];
            app.nPutSlider.Value = 1;
            app.nCallSlider = uislider(app.LeftPanel);
            app.nCallSlider.Limits = [0 5];
            app.nCallSlider.MajorTicks = [0 1 2 3 4 5];
            app.nCallSlider.ValueChangedFcn = createCallbackFcn(app, @nCallSliderValueChanged, true);
            app.nCallSlider.MinorTicks = [];
            app.nCallSlider.Value = 1;
            app.TradeTypeListBox = uilistbox(app.LeftPanel);
            app.TradeTypeListBox.Items = {'Buy Call-Buy Put', 'Buy Call-Sell Put', 'Sell Call-Buy Put', 'Sell Call-Sell Put'};
            app.TradeTypeListBox.ClickedFcn = createCallbackFcn(app, @TradeTypeListBoxClicked, true);
            app.TradeTypeListBox.Value = 'Buy Call-Buy Put';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Stock Price')
            ylabel(app.UIAxes, 'Pay-off at maturity')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_profile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end