classdef app_profile_2X < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PLabel_2                  matlab.ui.control.Label
        CLabel_2                  matlab.ui.control.Label
        X1Label_2                 matlab.ui.control.Label
        n2_CallSliderLabel        matlab.ui.control.Label
        Trade2TypeListBoxLabel    matlab.ui.control.Label
        Delta2_PutSliderLabel     matlab.ui.control.Label
        Delta2_CallSliderLabel    matlab.ui.control.Label
        n2_PutSliderLabel         matlab.ui.control.Label
        Trade1TypeListBox         matlab.ui.control.ListBox
        Trade2TypeListBox         matlab.ui.control.ListBox
        StockPrice                matlab.ui.control.NumericEditField
        X1Val                     matlab.ui.control.NumericEditField
        X2Val                     matlab.ui.control.NumericEditField
        Call1_Premium             matlab.ui.control.NumericEditField
        Call2_Premium             matlab.ui.control.NumericEditField
        Put1_Premium              matlab.ui.control.NumericEditField
        Put2_Premium              matlab.ui.control.NumericEditField
        n1_CallSlider             matlab.ui.control.Slider
        n2_CallSlider             matlab.ui.control.Slider
        n1_PutSlider              matlab.ui.control.Slider
        n2_PutSlider              matlab.ui.control.Slider
        Delta1_CallSlider         matlab.ui.control.Slider
        Delta2_CallSlider         matlab.ui.control.Slider
        Delta1_PutSlider          matlab.ui.control.Slider
        Delta2_PutSlider          matlab.ui.control.Slider
        StockRange                matlab.ui.control.NumericEditField
        Y_min                     matlab.ui.control.NumericEditField
        Y_max                     matlab.ui.control.NumericEditField
        Y_maxEditFieldLabel       matlab.ui.control.Label
        Y_minEditFieldLabel       matlab.ui.control.Label
        StockRangeEditFieldLabel  matlab.ui.control.Label
        PLabel                    matlab.ui.control.Label
        CLabel                    matlab.ui.control.Label
        X1Label                   matlab.ui.control.Label
        StockPriceEditFieldLabel  matlab.ui.control.Label
        Trade1TypeListBoxLabel    matlab.ui.control.Label
        Delta1_PutSliderLabel     matlab.ui.control.Label
        Delta1_CallSliderLabel    matlab.ui.control.Label
        CallsLabel                matlab.ui.control.Label
        PutsLabel                 matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function update_values(app)
            global stock_price 
            global X1 X2
            global call1_premium call2_premium 
            global put1_premium put2_premium 
            global stock_range
            global S_min S_max Y_min Y_max S_grid
            global call1_at_maturity call2_at_maturity 
            global put1_at_maturity put2_at_maturity
          
            stock_price = app.StockPrice.Value;
            X1 = app.X1Val.Value; X2 = app.X2Val.Value;
            call1_premium = app.Call1_Premium.Value; call2_premium = app.Call2_Premium.Value;
            put1_premium = app.Put1_Premium.Value; put2_premium = app.Put2_Premium.Value;

            stock_range = app.StockRange.Value;

            S_min = floor(stock_price*(1-stock_range));
            S_max = ceil(stock_price*(1+stock_range));
            S_grid = (S_min:1:S_max);
            Y_min = app.Y_min.Value; 
            Y_max = app.Y_max.Value; 

            call1_at_maturity = max(S_grid-X1,0);
            put1_at_maturity = max(X1-S_grid,0);
            call2_at_maturity = max(S_grid-X2,0);
            put2_at_maturity = max(X2-S_grid,0);
        end
        
        function plot_profile(app)
            global n1_call Delta1_call n2_call Delta2_call
            global n1_put Delta1_put n2_put Delta2_put
            global stock_price 
            global call1_premium put1_premium
            global call2_premium put2_premium
            global S_min S_max Y_min Y_max S_grid
            global call1_at_maturity call2_at_maturity  
            global put1_at_maturity put2_at_maturity
          
            switch app.Trade1TypeListBox.Value
                case 'Buy Call-Buy Put'
                    y1_option = n1_call*(call1_at_maturity - call1_premium) + n1_put*(put1_at_maturity - put1_premium);
                    y1_stock  = n1_call*Delta1_call*(stock_price - S_grid) + n1_put*Delta1_put*(stock_price - S_grid);
                    Effective_Delta1 = -n1_call*Delta1_call - n1_put*Delta1_put; 
                case 'Buy Call-Sell Put'
                    y1_option = n1_call*(call1_at_maturity - call1_premium) + n1_put*(put1_premium - put1_at_maturity);
                    y1_stock  = n1_call*Delta1_call*(stock_price - S_grid) + n1_put*Delta1_put*(S_grid - stock_price);
                    Effective_Delta1 = -n1_call*Delta1_call + n1_put*Delta1_put; 
                case 'Sell Call-Buy Put'
                    y1_option = n1_call*(call1_premium - call1_at_maturity) + n1_put*(put1_at_maturity - put1_premium);
                    y1_stock  = n1_call*Delta1_call*(S_grid - stock_price) + n1_put*Delta1_put*(stock_price - S_grid);
                    Effective_Delta1 = n1_call*Delta1_call - n1_put*Delta1_put;
                case 'Sell Call-Sell Put'
                    y1_option = n1_call*(call1_premium - call1_at_maturity) + n1_put*(put1_premium - put1_at_maturity);
                    y1_stock  = n1_call*Delta1_call*(S_grid - stock_price) + n1_put*Delta1_put*(S_grid - stock_price);
                    Effective_Delta1 = n1_call*Delta1_call + n1_put*Delta1_put;
            end
            y1 = 100*(y1_option + y1_stock);

            hold(app.UIAxes,'on'); 
            cla(app.UIAxes); 

            crimson = [0.6350 0.0780 0.1840]; 

            plot(app.UIAxes,S_grid,y1,'Color',crimson,'LineWidth',2); 
            z = line(app.UIAxes,S_grid,0*S_grid,'LineWidth',2); z.Color = 'k';

            app.UIAxes.YLim = [Y_min,Y_max];
            app.UIAxes.XLim = [S_min,S_max];

            switch app.Trade2TypeListBox.Value
                case 'Buy Call-Buy Put'
                    y2_option = n2_call*(call2_at_maturity - call2_premium) + n2_put*(put2_at_maturity - put2_premium);
                    y2_stock  = n2_call*Delta2_call*(stock_price - S_grid) + n2_put*Delta2_put*(stock_price - S_grid);
                    Effective_Delta2 = -n2_call*Delta2_call - n2_put*Delta2_put;
                case 'Buy Call-Sell Put'
                    y2_option = n2_call*(call2_at_maturity - call2_premium) + n2_put*(put2_premium - put2_at_maturity);
                    y2_stock  = n2_call*Delta2_call*(stock_price - S_grid) + n2_put*Delta2_put*(S_grid - stock_price);
                    Effective_Delta2 = -n2_call*Delta2_call + n2_put*Delta2_put;
                case 'Sell Call-Buy Put'
                    y2_option = n2_call*(call2_premium - call2_at_maturity) + n2_put*(put2_at_maturity - put2_premium);
                    y2_stock  = n2_call*Delta2_call*(S_grid - stock_price) + n2_put*Delta2_put*(stock_price - S_grid);
                    Effective_Delta2 = n2_call*Delta2_call - n2_put*Delta2_put;
                case 'Sell Call-Sell Put'
                    y2_option = n2_call*(call2_premium - call2_at_maturity) + n2_put*(put2_premium - put2_at_maturity);
                    y2_stock  = n2_call*Delta2_call*(S_grid - stock_price) + n2_put*Delta2_put*(S_grid - stock_price);
                    Effective_Delta2 = n2_call*Delta2_call + n2_put*Delta2_put;
            end
            y2 = 100*(y2_option + y2_stock);

            plot(app.UIAxes,S_grid,y2,'m','LineWidth',2);
            
            y = y1 + y2;
            plot(app.UIAxes,S_grid,y,'b-','LineWidth',2);

            Effective_Delta = Effective_Delta1 + Effective_Delta2;

            yp = subplus(y); y_neg = find(yp==0); y_pos = find(yp > 0);
            if ~isempty(y_neg) && ~isempty(y_pos)
                if S_grid(y_neg(1)) > S_min && S_grid(y_neg(end)) < S_max
                    pos_str = ['(',num2str(0),', ',num2str(S_grid(y_neg(1)-1)),') and (',num2str(S_grid(y_neg(end)+1)),', \infty)'];
                else
                    pos_range = S_grid(y_pos);
                    if y_pos(1) == 1
                        pos_str = ['(',num2str(0),',',num2str(pos_range(end)),')'];
                    else
                        pos_str = ['(',num2str(pos_range(1)), ', ',num2str(pos_range(end)),')'];
                    end
                end
            elseif isempty(y_neg)
                pos_str = '(0,\infty)';
            else
                pos_str = '\infty';
            end

            title_str = {['100\Delta_{eff} = ',num2str(round(100*Effective_Delta)),...
                '. Make money if S \in ',pos_str],...
                ['n_{1,call} = ',num2str(n1_call),', n_{1,put} = ',num2str(n1_put)...
                 ', n_{2,call} = ',num2str(n2_call),', n_{2,put} = ',num2str(n2_put)],...
                 ['\Delta_{1,call} = ',num2str(Delta1_call,'%.2f'),', \Delta_{1,put} = ',num2str(Delta1_put,'%.2f')...
                 ', \Delta_{2,call} = ',num2str(Delta2_call,'%.2f'),', \Delta_{2,put} = ',num2str(Delta2_put,'%.2f')]};
            title(app.UIAxes, title_str,'FontSize',24);
            
            hold(app.UIAxes,'off'); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global n1_call n2_call
            global n1_put n2_put
            global Delta1_call Delta2_call 
            global Delta1_put Delta2_put

            n1_call = app.n1_CallSlider.Value; n2_call = app.n2_CallSlider.Value;
            n1_put = app.n1_PutSlider.Value; n2_put = app.n2_PutSlider.Value;
            Delta1_call = app.Delta1_CallSlider.Value; Delta2_call = app.Delta2_CallSlider.Value;
            Delta1_put = app.Delta1_PutSlider.Value; Delta2_put = app.Delta2_PutSlider.Value;

            update_values(app); plot_profile(app);
        end

        % Value changed function: n1_CallSlider
        function n1_CallSliderValueChanged(app, event)
            global n1_call
            n1_call = round(event.Value);
            app.n1_CallSlider.Value = n1_call;
            update_values(app); plot_profile(app);
        end

        % Value changed function: n1_PutSlider
        function n1_PutSliderValueChanged(app, event)
            global n1_put
            n1_put = round(event.Value);
            app.n1_PutSlider.Value = n1_put;
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta1_CallSlider
        function Delta1_CallSliderValueChanging(app, event)
            global Delta1_call
            Delta1_call = event.Value;
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta1_PutSlider
        function Delta1_PutSliderValueChanging(app, event)
            global Delta1_put
            Delta1_put = event.Value; 
            update_values(app); plot_profile(app);
        end

        % Clicked callback: Trade1TypeListBox
        function Trade1TypeListBoxClicked(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: StockPrice
        function StockPriceValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Y_max
        function Y_maxValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Y_min
        function Y_minValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Put1_Premium
        function Put1_PremiumValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: Call1_Premium
        function Call1_PremiumValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: X1Val
        function X1ValValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: StockRange
        function StockRangeValueChanged(app, event)
            update_values(app); plot_profile(app);
        end

        % Value changed function: X2Val
        function X2ValValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: Call2_Premium
        function Call2_PremiumValueChanged(app, event)
            update_values(app); plot_profile(app);            
        end

        % Value changed function: Put2_Premium
        function Put2_PremiumValueChanged(app, event)
             update_values(app); plot_profile(app);            
        end

        % Clicked callback: Trade2TypeListBox
        function Trade2TypeListBoxClicked(app, event)
             update_values(app); plot_profile(app);            
        end

        % Value changed function: n2_CallSlider
        function n2_CallSliderValueChanged(app, event)
            global n2_call
            n2_call = round(event.Value);
            app.n1_CallSlider.Value = n2_call; 
            update_values(app); plot_profile(app);
        end

        % Value changed function: n2_PutSlider
        function n2_PutSliderValueChanged(app, event)
            global n2_put
            n2_put = round(event.Value);
            app.n2_PutSlider.Value = n2_put;
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta2_CallSlider
        function Delta2_CallSliderValueChanging(app, event)
            global Delta2_call
            Delta2_call = event.Value; 
            update_values(app); plot_profile(app);
        end

        % Value changing function: Delta2_PutSlider
        function Delta2_PutSliderValueChanging(app, event)
            global Delta2_put
            Delta2_put = event.Value;
            update_values(app); plot_profile(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1758, 1758};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {903, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PutsLabel = uilabel(app.LeftPanel);
            app.PutsLabel.Text = 'n1_Put';
            app.CallsLabel = uilabel(app.LeftPanel);
            app.CallsLabel.Text = 'n1_Call';
            app.Delta1_CallSliderLabel = uilabel(app.LeftPanel);
            app.Delta1_CallSliderLabel.Text = 'Delta1_Call';
            app.Delta1_PutSliderLabel = uilabel(app.LeftPanel);
            app.Delta1_PutSliderLabel.Text = 'Delta1_Put';
            app.Trade1TypeListBoxLabel = uilabel(app.LeftPanel);
            app.Trade1TypeListBoxLabel.Text = 'Trade-1 Type';
            app.StockPriceEditFieldLabel = uilabel(app.LeftPanel);
            app.StockPriceEditFieldLabel.Text = 'Stock Price';
            app.X1Label = uilabel(app.LeftPanel);
            app.X1Label.Text = 'X1';
            app.CLabel = uilabel(app.LeftPanel);
            app.CLabel.Text = 'C1';
            app.PLabel = uilabel(app.LeftPanel);
            app.PLabel.Text = 'P1';
            app.StockRangeEditFieldLabel = uilabel(app.LeftPanel);
            app.StockRangeEditFieldLabel.Text = 'Stock Range';
            app.Y_minEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_minEditFieldLabel.Text = 'Y_min';
            app.Y_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_maxEditFieldLabel.Text = 'Y_max';
            app.Y_max = uieditfield(app.LeftPanel, 'numeric');
            app.Y_max.ValueChangedFcn = createCallbackFcn(app, @Y_maxValueChanged, true);
            app.Y_max.Value = 5000;
            app.Y_min = uieditfield(app.LeftPanel, 'numeric');
            app.Y_min.ValueChangedFcn = createCallbackFcn(app, @Y_minValueChanged, true);
            app.Y_min.Value = -5000;
            app.StockRange = uieditfield(app.LeftPanel, 'numeric');
            app.StockRange.ValueChangedFcn = createCallbackFcn(app, @StockRangeValueChanged, true);
            app.StockRange.Value = 0.2;
            app.Delta2_PutSlider = uislider(app.LeftPanel);
            app.Delta2_PutSlider.Limits = [-1 0];
            app.Delta2_PutSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0];
            app.Delta2_PutSlider.ValueChangingFcn = createCallbackFcn(app, @Delta2_PutSliderValueChanging, true);
            app.Delta1_PutSlider = uislider(app.LeftPanel);
            app.Delta1_PutSlider.Limits = [-1 0];
            app.Delta1_PutSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0];
            app.Delta1_PutSlider.ValueChangingFcn = createCallbackFcn(app, @Delta1_PutSliderValueChanging, true);
            app.Delta2_CallSlider = uislider(app.LeftPanel);
            app.Delta2_CallSlider.Limits = [0 1];
            app.Delta2_CallSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Delta2_CallSlider.ValueChangingFcn = createCallbackFcn(app, @Delta2_CallSliderValueChanging, true);
            app.Delta1_CallSlider = uislider(app.LeftPanel);
            app.Delta1_CallSlider.Limits = [0 1];
            app.Delta1_CallSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Delta1_CallSlider.ValueChangingFcn = createCallbackFcn(app, @Delta1_CallSliderValueChanging, true);
            app.n2_PutSlider = uislider(app.LeftPanel);
            app.n2_PutSlider.Limits = [0 5];
            app.n2_PutSlider.MajorTicks = [0 1 2 3 4 5];
            app.n2_PutSlider.ValueChangedFcn = createCallbackFcn(app, @n2_PutSliderValueChanged, true);
            app.n2_PutSlider.MinorTicks = [];
            app.n2_PutSlider.Value = 1;
            app.n1_PutSlider = uislider(app.LeftPanel);
            app.n1_PutSlider.Limits = [0 5];
            app.n1_PutSlider.MajorTicks = [0 1 2 3 4 5];
            app.n1_PutSlider.ValueChangedFcn = createCallbackFcn(app, @n1_PutSliderValueChanged, true);
            app.n1_PutSlider.MinorTicks = [];
            app.n1_PutSlider.Value = 1;
            app.n2_CallSlider = uislider(app.LeftPanel);
            app.n2_CallSlider.Limits = [0 5];
            app.n2_CallSlider.MajorTicks = [0 1 2 3 4 5];
            app.n2_CallSlider.ValueChangedFcn = createCallbackFcn(app, @n2_CallSliderValueChanged, true);
            app.n2_CallSlider.MinorTicks = [];
            app.n2_CallSlider.Value = 1;
            app.n1_CallSlider = uislider(app.LeftPanel);
            app.n1_CallSlider.Limits = [0 5];
            app.n1_CallSlider.MajorTicks = [0 1 2 3 4 5];
            app.n1_CallSlider.ValueChangedFcn = createCallbackFcn(app, @n1_CallSliderValueChanged, true);
            app.n1_CallSlider.MinorTicks = [];
            app.n1_CallSlider.Value = 1;
            app.Put2_Premium = uieditfield(app.LeftPanel, 'numeric');
            app.Put2_Premium.ValueChangedFcn = createCallbackFcn(app, @Put2_PremiumValueChanged, true);
            app.Put2_Premium.Value = 10.9;
            app.Put1_Premium = uieditfield(app.LeftPanel, 'numeric');
            app.Put1_Premium.ValueChangedFcn = createCallbackFcn(app, @Put1_PremiumValueChanged, true);
            app.Put1_Premium.Value = 5.63;
            app.Call2_Premium = uieditfield(app.LeftPanel, 'numeric');
            app.Call2_Premium.ValueChangedFcn = createCallbackFcn(app, @Call2_PremiumValueChanged, true);
            app.Call2_Premium.Value = 7.9;
            app.Call1_Premium = uieditfield(app.LeftPanel, 'numeric');
            app.Call1_Premium.ValueChangedFcn = createCallbackFcn(app, @Call1_PremiumValueChanged, true);
            app.Call1_Premium.Value = 11.75;
            app.X2Val = uieditfield(app.LeftPanel, 'numeric');
            app.X2Val.ValueChangedFcn = createCallbackFcn(app, @X2ValValueChanged, true);
            app.X2Val.Value = 180;
            app.X1Val = uieditfield(app.LeftPanel, 'numeric');
            app.X1Val.ValueChangedFcn = createCallbackFcn(app, @X1ValValueChanged, true);
            app.X1Val.Value = 170;
            app.StockPrice = uieditfield(app.LeftPanel, 'numeric');
            app.StockPrice.ValueChangedFcn = createCallbackFcn(app, @StockPriceValueChanged, true);
            app.StockPrice.Value = 175;
            app.Trade2TypeListBox = uilistbox(app.LeftPanel);
            app.Trade2TypeListBox.Items = {'Buy Call-Buy Put', 'Buy Call-Sell Put', 'Sell Call-Buy Put', 'Sell Call-Sell Put'};
            app.Trade2TypeListBox.ClickedFcn = createCallbackFcn(app, @Trade2TypeListBoxClicked, true);
            app.Trade2TypeListBox.Value = 'Sell Call-Sell Put';
            app.Trade1TypeListBox = uilistbox(app.LeftPanel);
            app.Trade1TypeListBox.Items = {'Buy Call-Buy Put', 'Buy Call-Sell Put', 'Sell Call-Buy Put', 'Sell Call-Sell Put'};
            app.Trade1TypeListBox.ClickedFcn = createCallbackFcn(app, @Trade1TypeListBoxClicked, true);
            app.Trade1TypeListBox.Value = 'Sell Call-Sell Put';
            app.n2_PutSliderLabel = uilabel(app.LeftPanel);
            app.n2_PutSliderLabel.Text = 'n2_Put';
            app.Delta2_CallSliderLabel = uilabel(app.LeftPanel);
            app.Delta2_CallSliderLabel.Text = 'Delta2_Call';
            app.Delta2_PutSliderLabel = uilabel(app.LeftPanel);
            app.Delta2_PutSliderLabel.Text = 'Delta2_Put';
            app.Trade2TypeListBoxLabel = uilabel(app.LeftPanel);
            app.Trade2TypeListBoxLabel.Text = 'Trade-2 Type';
            app.n2_CallSliderLabel = uilabel(app.LeftPanel);
            app.n2_CallSliderLabel.Text = 'n2_Call';
            app.X1Label_2 = uilabel(app.LeftPanel);
            app.X1Label_2.Text = 'X2';
            app.CLabel_2 = uilabel(app.LeftPanel);
            app.CLabel_2.Text = 'C2';
            app.PLabel_2 = uilabel(app.LeftPanel);
            app.PLabel_2.Text = 'P2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Stock Price')
            ylabel(app.UIAxes, 'Pay-off at maturity')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_profile_2X

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end