classdef app_spread < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        P2Label                   matlab.ui.control.Label
        P1Label                   matlab.ui.control.Label
        SpreadTypeListBox         matlab.ui.control.ListBox
        n1Slider                  matlab.ui.control.Slider
        n2Slider                  matlab.ui.control.Slider
        Delta1Slider              matlab.ui.control.Slider
        Delta2Slider              matlab.ui.control.Slider
        StockRangeEditFieldLabel  matlab.ui.control.Label
        X1                        matlab.ui.control.NumericEditField
        X2                        matlab.ui.control.NumericEditField
        C1                        matlab.ui.control.NumericEditField
        C2                        matlab.ui.control.NumericEditField
        P1                        matlab.ui.control.NumericEditField
        P2                        matlab.ui.control.NumericEditField
        StockPrice                matlab.ui.control.NumericEditField
        StockRange                matlab.ui.control.NumericEditField
        Y_min                     matlab.ui.control.NumericEditField
        Y_max                     matlab.ui.control.NumericEditField
        Y_maxEditFieldLabel       matlab.ui.control.Label
        Y_minEditFieldLabel       matlab.ui.control.Label
        StockPriceLabel           matlab.ui.control.Label
        C2EditFieldLabel          matlab.ui.control.Label
        X2EditFieldLabel          matlab.ui.control.Label
        C1EditFieldLabel          matlab.ui.control.Label
        X1EditFieldLabel          matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Delta2SliderLabel         matlab.ui.control.Label
        Delta1SliderLabel         matlab.ui.control.Label
        CallsLabel                matlab.ui.control.Label
        PutsLabel                 matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function update_values(app)
            global C1 C2 P1 P2
            global stock_price
            global S_grid Y_min Y_max
            global stock_range S_min S_max
            global X1 X2 
            global C1_at_maturity C2_at_maturity
            global P1_at_maturity P2_at_maturity

            stock_price = app.StockPrice.Value;
            stock_range = app.StockRange.Value;

            X1 = app.X1.Value; X2 = app.X2.Value;
            C1 = app.C1.Value; C2 = app.C2.Value;
            P1 = app.P1.Value; P2 = app.P2.Value;

            S_min = floor(stock_price*(1-stock_range));
            S_max = ceil(stock_price*(1+stock_range));
            S_grid = (S_min:1:S_max);
            Y_min = app.Y_min.Value;
            Y_max = app.Y_max.Value;

            C1_at_maturity = max(S_grid-X1,0);
            C2_at_maturity = max(S_grid-X2,0);

            P1_at_maturity = max(X1-S_grid,0);
            P2_at_maturity = max(X2-S_grid,0);
        end

        function plot_profile(app)
            global n1 n2 C1 C2 P1 P2 Delta_1 Delta_2
            global stock_price 
            global S_grid Y_min Y_max
            global S_min S_max
            global C1_at_maturity C2_at_maturity
            global P1_at_maturity P2_at_maturity

            switch app.SpreadTypeListBox.Value
                case 'Long Call Bull'
                    %disp(['C1 = ',num2str(C1)]); disp(['C2 = ',num2str(C2)]); 
                    y_option_1 = n1*(C1_at_maturity - C1);
                    y_option_2 = n2*(C2 - C2_at_maturity);
                    Effective_Delta = n2*Delta_2 - n1*Delta_1;
                case 'Long Call Bear'
                    y_option_1 = n1*(C1 - C1_at_maturity);
                    y_option_2 = n2*(C2_at_maturity - C2);
                    Effective_Delta = n1*Delta_1 - n2*Delta_2;
                case 'Long Put Bull'
                    y_option_1 = n1*(P1_at_maturity - P1);
                    y_option_2 = n2*(P2 - P2_at_maturity);
                    Effective_Delta = -(n2*Delta_2 - n1*Delta_1);
                case 'Long Put Bear'
                    y_option_1 = n1*(P1 - P1_at_maturity);
                    y_option_2 = n2*(P2_at_maturity - P2);
                    Effective_Delta = -(n1*Delta_1 - n2*Delta_2);
            end
            %disp(['stock_price = ',num2str(stock_price)]);
            y_stock  = Effective_Delta*(stock_price - S_grid);
            y = 100*(y_option_1 + y_option_2 + y_stock);             yp = subplus(y); y_neg = find(yp==0); y_pos = find(yp > 0);
            if ~isempty(y_neg) && ~isempty(y_pos)
                if S_grid(y_neg(1)) > S_min && S_grid(y_neg(end)) < S_max
                    pos_str = ['(',num2str(0),', ',num2str(S_grid(y_neg(1)-1)),') and (',num2str(S_grid(y_neg(end)+1)),', \infty)'];
                else
                    pos_range = S_grid(y_pos);
                    if y_pos(1) == 1
                        pos_str = ['(',num2str(0),',',num2str(pos_range(end)),')'];
                    else
                        pos_str = ['(',num2str(pos_range(1)), ', ',num2str(pos_range(end)),')'];
                    end
                end
            elseif isempty(y_neg)
                pos_str = '(0,\infty)';
            else
                pos_str = '\infty';
            end

            title_str = {['100\Delta_{effective} = ',num2str(round(100*Effective_Delta)),...
                '. Make money if S \in ',pos_str]...
                ['n_1 = ',num2str(n1),', n_2 = ',num2str(n2)...
                ', \Delta_1 = ',num2str(Delta_1,'%.2f'),', \Delta_2 = ',num2str(Delta_2,'%.2f'),...
                ', Effective \Delta = ',num2str(Effective_Delta,'%.2f')]};

            plot(app.UIAxes,S_grid,y,'LineWidth',2);
            z = line(app.UIAxes,S_grid,0*S_grid,'LineWidth',2); z.Color = 'k';
            app.UIAxes.YLim = [Y_min,Y_max];
            app.UIAxes.XLim = [S_min,S_max];
            title(app.UIAxes, title_str,'FontSize',24);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global n1 n2 Delta_1 Delta_2
            n1 = app.n1Slider.Value; n2 = app.n2Slider.Value;
            Delta_1 = app.Delta1Slider.Value; Delta_2 = app.Delta2Slider.Value;
            update_values(app); plot_profile(app);
        end

        % Value changed function: n1Slider
        function n1SliderValueChanged(app, event)
            global n1
            n1 = round(event.Value); app.n1Slider.Value = n1;
            update_values(app); plot_profile(app); 
        end

        % Value changed function: n2Slider
        function n2SliderValueChanged(app, event)
            global n2
            n2 = round(event.Value); app.n2Slider.Value = n2;
            update_values(app); plot_profile(app); 
        end

        % Value changing function: Delta1Slider
        function Delta1SliderValueChanging(app, event)
            global Delta_1
            Delta_1 = event.Value;
            update_values(app); plot_profile(app); 
        end

        % Value changing function: Delta2Slider
        function Delta2SliderValueChanging(app, event)
            global Delta_2
            Delta_2 = event.Value;
            update_values(app); plot_profile(app); 
        end

        % Clicked callback: SpreadTypeListBox
        function SpreadTypeListBoxClicked(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: StockPrice
        function StockPriceValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: X1
        function X1ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: X2
        function X2ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: C1
        function C1ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: C2
        function C2ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: Y_min
        function Y_minValueChanged(app, event)
            update_values(app); plot_profile(app);  
        end

        % Value changed function: Y_max
        function Y_maxValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: StockRange
        function StockRangeValueChanged(app, event)
            update_values(app); plot_profile(app);      
        end

        % Value changed function: P1
        function P1ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Value changed function: P2
        function P2ValueChanged(app, event)
            update_values(app); plot_profile(app); 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1758, 1758};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {514, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Call Spread';
            app.PutsLabel = uilabel(app.LeftPanel);
            app.PutsLabel.Text = 'n2';
            app.CallsLabel = uilabel(app.LeftPanel);
            app.CallsLabel.Text = 'n1';
            app.Delta1SliderLabel = uilabel(app.LeftPanel);
            app.Delta1SliderLabel.Text = 'Delta1';
            app.Delta2SliderLabel = uilabel(app.LeftPanel);
            app.Delta2SliderLabel.Text = 'Delta2';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Spread Type';
            app.X1EditFieldLabel = uilabel(app.LeftPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.C1EditFieldLabel = uilabel(app.LeftPanel);
            app.C1EditFieldLabel.Text = 'C1';
            app.X2EditFieldLabel = uilabel(app.LeftPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.C2EditFieldLabel = uilabel(app.LeftPanel);
            app.C2EditFieldLabel.Text = 'C2';
            app.StockPriceLabel = uilabel(app.LeftPanel);
            app.StockPriceLabel.Text = 'Stock Price';
            app.Y_minEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_minEditFieldLabel.Text = 'Y_min';
            app.Y_maxEditFieldLabel = uilabel(app.LeftPanel);
            app.Y_maxEditFieldLabel.Text = 'Y_max';
            app.Y_max = uieditfield(app.LeftPanel, 'numeric');
            app.Y_max.ValueChangedFcn = createCallbackFcn(app, @Y_maxValueChanged, true);
            app.Y_max.Value = 500;
            app.Y_min = uieditfield(app.LeftPanel, 'numeric');
            app.Y_min.ValueChangedFcn = createCallbackFcn(app, @Y_minValueChanged, true);
            app.Y_min.Value = -500;
            app.StockRange = uieditfield(app.LeftPanel, 'numeric');
            app.StockRange.ValueChangedFcn = createCallbackFcn(app, @StockRangeValueChanged, true);
            app.StockRange.Value = 0.2;
            app.StockPrice = uieditfield(app.LeftPanel, 'numeric');
            app.StockPrice.ValueChangedFcn = createCallbackFcn(app, @StockPriceValueChanged, true);
            app.StockPrice.Value = 636.52;
            app.P2 = uieditfield(app.LeftPanel, 'numeric');
            app.P2.ValueChangedFcn = createCallbackFcn(app, @P2ValueChanged, true);
            app.P2.Value = 8;
            app.P1 = uieditfield(app.LeftPanel, 'numeric');
            app.P1.ValueChangedFcn = createCallbackFcn(app, @P1ValueChanged, true);
            app.P1.Value = 5;
            app.C2 = uieditfield(app.LeftPanel, 'numeric');
            app.C2.ValueChangedFcn = createCallbackFcn(app, @C2ValueChanged, true);
            app.C2.Value = 20.05;
            app.C1 = uieditfield(app.LeftPanel, 'numeric');
            app.C1.ValueChangedFcn = createCallbackFcn(app, @C1ValueChanged, true);
            app.C1.Value = 24.82;
            app.X2 = uieditfield(app.LeftPanel, 'numeric');
            app.X2.ValueChangedFcn = createCallbackFcn(app, @X2ValueChanged, true);
            app.X2.Value = 630;
            app.X1 = uieditfield(app.LeftPanel, 'numeric');
            app.X1.ValueChangedFcn = createCallbackFcn(app, @X1ValueChanged, true);
            app.X1.Value = 620;
            app.StockRangeEditFieldLabel = uilabel(app.LeftPanel);
            app.StockRangeEditFieldLabel.Text = 'Stock Range';
            app.Delta2Slider = uislider(app.LeftPanel);
            app.Delta2Slider.Limits = [0 1];
            app.Delta2Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Delta2Slider.ValueChangingFcn = createCallbackFcn(app, @Delta2SliderValueChanging, true);
            app.Delta1Slider = uislider(app.LeftPanel);
            app.Delta1Slider.Limits = [0 1];
            app.Delta1Slider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Delta1Slider.ValueChangingFcn = createCallbackFcn(app, @Delta1SliderValueChanging, true);
            app.n2Slider = uislider(app.LeftPanel);
            app.n2Slider.Limits = [0 5];
            app.n2Slider.MajorTicks = [0 1 2 3 4 5];
            app.n2Slider.ValueChangedFcn = createCallbackFcn(app, @n2SliderValueChanged, true);
            app.n2Slider.MinorTicks = [];
            app.n2Slider.Value = 1;
            app.n1Slider = uislider(app.LeftPanel);
            app.n1Slider.Limits = [0 5];
            app.n1Slider.MajorTicks = [0 1 2 3 4 5];
            app.n1Slider.ValueChangedFcn = createCallbackFcn(app, @n1SliderValueChanged, true);
            app.n1Slider.MinorTicks = [];
            app.n1Slider.Value = 1;
            app.SpreadTypeListBox = uilistbox(app.LeftPanel);
            app.SpreadTypeListBox.Items = {'Long Call Bull', 'Long Call Bear', 'Long Put Bull', 'Long Put Bear'};
            app.SpreadTypeListBox.ClickedFcn = createCallbackFcn(app, @SpreadTypeListBoxClicked, true);
            app.SpreadTypeListBox.Value = 'Long Call Bull';
            app.P1Label = uilabel(app.LeftPanel);
            app.P1Label.Text = 'P1';
            app.P2Label = uilabel(app.LeftPanel);
            app.P2Label.Text = 'P2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Stock Price')
            ylabel(app.UIAxes, 'Pay-off at maturity')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_spread

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end